classdef bidirectionalPumping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        ForwardSignalInitValue      matlab.ui.control.NumericEditField
        SignalPoweruWSliderLabel_2  matlab.ui.control.Label
        BackPowerInitValue          matlab.ui.control.NumericEditField
        SignalPoweruWSliderLabel_3  matlab.ui.control.Label
        InitialLabel                matlab.ui.control.Label
        uWLabel_2                   matlab.ui.control.Label
        mWLabel_2                   matlab.ui.control.Label
        mWLabel_3                   matlab.ui.control.Label
        ForwardPowerInitValue       matlab.ui.control.NumericEditField
        SignalPoweruWSliderLabel_7  matlab.ui.control.Label
        BackSignalInitValue         matlab.ui.control.NumericEditField
        SignalPoweruWSliderLabel_8  matlab.ui.control.Label
        uWLabel_4                   matlab.ui.control.Label
        DistancekmSliderLabel       matlab.ui.control.Label
        DistancekmSlider            matlab.ui.control.Slider
        FSignaluWSlider             matlab.ui.control.Slider
        distanceEditFieldLabel      matlab.ui.control.Label
        distanceEditField           matlab.ui.control.NumericEditField
        ForwardPumpmWLabel          matlab.ui.control.Label
        ForwardPumpmWSlider         matlab.ui.control.Slider
        BackSignaluWLabel           matlab.ui.control.Label
        BackSignaluWSlider          matlab.ui.control.Slider
        BSignalEditField            matlab.ui.control.NumericEditField
        FSignaluWSliderLabel        matlab.ui.control.Label
        EndLabel                    matlab.ui.control.Label
        BPumpEditField              matlab.ui.control.NumericEditField
        uWLabel_5                   matlab.ui.control.Label
        uWLabel_6                   matlab.ui.control.Label
        mWLabel_4                   matlab.ui.control.Label
        mWLabel_5                   matlab.ui.control.Label
        ForwardSignalEndValue       matlab.ui.control.NumericEditField
        BackSignalEndValue          matlab.ui.control.NumericEditField
        ForwardPowerEndValue        matlab.ui.control.NumericEditField
        BackPowerEndValue           matlab.ui.control.NumericEditField
        KmLabel                     matlab.ui.control.Label
        FSignalEditField            matlab.ui.control.NumericEditField
        dEditField                  matlab.ui.control.NumericEditField
        ForwardEditField            matlab.ui.control.NumericEditField
        BackPumpmWLabel_2           matlab.ui.control.Label
        BackPumpmWSlider            matlab.ui.control.Slider
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        distance=0; % Description
        ForwardSignal=0;
        BackSignal=0;
        ForwardPower=0;
        BackPower=0;
        opts = odeset('RelTol',1e-4);
        alpha_R=log(10)*0.23/10;
        alpha_p=log(10)*0.19/10;
    end
    
    
    methods (Access = private)
        
        function result = UpdateResult(app)
            PP1=app.ForwardSignal;
            signalEnd=app.BackSignal;
            PP2=signalEnd;
            PR1=app.ForwardPower;
            pumpEnd=app.BackPower*exp(-app.alpha_R*app.distance);
            PR2=pumpEnd;
            [z,p] = ode45(@ramanPump, [-1e-6,app.distance], [PP1;PP2;PR1;PR2],app.opts);
            plot(app.UIAxes_2,z,p(:,1),z,p(:,2));
            legend(app.UIAxes_2,'forward Signal','Back Signal')
            plot(app.UIAxes_4,z,p(:,3),z,p(:,4));
            legend(app.UIAxes_4,'forward Pump','Back Pump')
            app.distanceEditField.Value=app.distance;
            app.ForwardSignalInitValue.Value=p(1,1)*1e3;
            app.BackSignalInitValue.Value=p(1,2)*1e3;
            app.ForwardPowerInitValue.Value=p(1,3);
            app.BackPowerInitValue.Value=p(1,4);
            app.ForwardSignalEndValue.Value=p(end,1)*1e3;
            app.BackSignalEndValue.Value=p(end,2)*1e3;
            app.ForwardPowerEndValue.Value=p(end,3);
            app.BackPowerEndValue.Value=p(end,4);
            result=p;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: DistancekmSlider
        function DistancekmSliderValueChanging(app, event)
            app.distance = event.Value;
            UpdateResult(app);
        end

        % Value changing function: FSignaluWSlider
        function FSignaluWSliderValueChanging(app, event)
            app.ForwardSignal = event.Value*1e-3;
            UpdateResult(app);
        end

        % Value changing function: BackPumpmWSlider
        function BackPumpmWSliderValueChanging(app, event)
            app.BackPower = event.Value;
            UpdateResult(app);
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            app.distance = app.dEditField.Value;
            app.DistancekmSlider.Value=app.distance;
            UpdateResult(app);
        end

        % Value changed function: FSignalEditField
        function FSignalEditFieldValueChanged(app, event)
            app.ForwardSignal = app.FSignalEditField.Value*1e-3;
            app.FSignaluWSlider.Value=app.ForwardSignal*1e3;
            UpdateResult(app);
        end

        % Value changed function: BPumpEditField
        function BackEditFieldValueChanged(app, event)
            app.BackPower = app.BPumpEditField.Value;
            app.BackPumpmWSlider.Value=app.BackPower;
            UpdateResult(app);
        end

        % Value changing function: ForwardPumpmWSlider
        function ForwardPumpmWSliderValueChanging(app, event)
            app.ForwardPower = event.Value;
            UpdateResult(app);
        end

        % Value changed function: ForwardEditField
        function ForwardEditFieldValueChanged(app, event)
            app.ForwardPower = app.ForwardEditField.Value;
            app.FSignaluWSlider.Value=app.ForwardPower;
            UpdateResult(app);
        end

        % Value changed function: BSignalEditField
        function BSignalEditFieldValueChanged(app, event)
            app.BackSignal = app.BSignalEditField.Value*1e-3;
            app.BackSignaluWSlider.Value=app.BackSignal*1e3;
            UpdateResult(app);
        end

        % Value changing function: BackSignaluWSlider
        function BackSignaluWSliderValueChanging(app, event)
            app.BackSignal = event.Value*1e-3;
            UpdateResult(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {581, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ForwardSignalInitValue = uieditfield(app.GridLayout3, 'numeric');
            app.SignalPoweruWSliderLabel_2 = uilabel(app.GridLayout3);
            app.SignalPoweruWSliderLabel_2.Text = 'ForwardSignal';
            app.BackPowerInitValue = uieditfield(app.GridLayout3, 'numeric');
            app.SignalPoweruWSliderLabel_3 = uilabel(app.GridLayout3);
            app.SignalPoweruWSliderLabel_3.Text = 'BackPower';
            app.InitialLabel = uilabel(app.GridLayout3);
            app.InitialLabel.Text = 'Initial';
            app.uWLabel_2 = uilabel(app.GridLayout3);
            app.uWLabel_2.Text = 'uW';
            app.mWLabel_2 = uilabel(app.GridLayout3);
            app.mWLabel_2.Text = 'mW';
            app.mWLabel_3 = uilabel(app.GridLayout3);
            app.mWLabel_3.Text = 'mW';
            app.ForwardPowerInitValue = uieditfield(app.GridLayout3, 'numeric');
            app.SignalPoweruWSliderLabel_7 = uilabel(app.GridLayout3);
            app.SignalPoweruWSliderLabel_7.Text = 'ForwardPower';
            app.BackSignalInitValue = uieditfield(app.GridLayout3, 'numeric');
            app.SignalPoweruWSliderLabel_8 = uilabel(app.GridLayout3);
            app.SignalPoweruWSliderLabel_8.Text = 'BackSignal';
            app.uWLabel_4 = uilabel(app.GridLayout3);
            app.uWLabel_4.Text = 'uW';
            app.DistancekmSliderLabel = uilabel(app.GridLayout3);
            app.DistancekmSliderLabel.Text = 'Distance/km';
            app.DistancekmSlider = uislider(app.GridLayout3);
            app.DistancekmSlider.Limits = [0 150];
            app.DistancekmSlider.ValueChangingFcn = createCallbackFcn(app, @DistancekmSliderValueChanging, true);
            app.DistancekmSlider.Value = 1;
            app.FSignaluWSlider = uislider(app.GridLayout3);
            app.FSignaluWSlider.Limits = [0 1000];
            app.FSignaluWSlider.ValueChangingFcn = createCallbackFcn(app, @FSignaluWSliderValueChanging, true);
            app.distanceEditFieldLabel = uilabel(app.GridLayout3);
            app.distanceEditFieldLabel.Text = 'distance';
            app.distanceEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ForwardPumpmWLabel = uilabel(app.GridLayout3);
            app.ForwardPumpmWLabel.Text = 'Forward Pump/mW';
            app.ForwardPumpmWSlider = uislider(app.GridLayout3);
            app.ForwardPumpmWSlider.Limits = [0 500];
            app.ForwardPumpmWSlider.ValueChangingFcn = createCallbackFcn(app, @ForwardPumpmWSliderValueChanging, true);
            app.BackSignaluWLabel = uilabel(app.GridLayout3);
            app.BackSignaluWLabel.Text = 'Back Signal/uW';
            app.BackSignaluWSlider = uislider(app.GridLayout3);
            app.BackSignaluWSlider.Limits = [0 1000];
            app.BackSignaluWSlider.ValueChangingFcn = createCallbackFcn(app, @BackSignaluWSliderValueChanging, true);
            app.BSignalEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BSignalEditField.ValueChangedFcn = createCallbackFcn(app, @BSignalEditFieldValueChanged, true);
            app.FSignaluWSliderLabel = uilabel(app.GridLayout3);
            app.FSignaluWSliderLabel.Text = 'Forward Signal/uW';
            app.EndLabel = uilabel(app.GridLayout3);
            app.EndLabel.Text = 'End';
            app.BPumpEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BPumpEditField.ValueChangedFcn = createCallbackFcn(app, @BackEditFieldValueChanged, true);
            app.uWLabel_5 = uilabel(app.GridLayout3);
            app.uWLabel_5.Text = 'uW';
            app.uWLabel_6 = uilabel(app.GridLayout3);
            app.uWLabel_6.Text = 'uW';
            app.mWLabel_4 = uilabel(app.GridLayout3);
            app.mWLabel_4.Text = 'mW';
            app.mWLabel_5 = uilabel(app.GridLayout3);
            app.mWLabel_5.Text = 'mW';
            app.ForwardSignalEndValue = uieditfield(app.GridLayout3, 'numeric');
            app.BackSignalEndValue = uieditfield(app.GridLayout3, 'numeric');
            app.ForwardPowerEndValue = uieditfield(app.GridLayout3, 'numeric');
            app.BackPowerEndValue = uieditfield(app.GridLayout3, 'numeric');
            app.KmLabel = uilabel(app.GridLayout3);
            app.KmLabel.Text = 'Km';
            app.FSignalEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FSignalEditField.ValueChangedFcn = createCallbackFcn(app, @FSignalEditFieldValueChanged, true);
            app.dEditField = uieditfield(app.GridLayout3, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.ForwardEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ForwardEditField.ValueChangedFcn = createCallbackFcn(app, @ForwardEditFieldValueChanged, true);
            app.BackPumpmWLabel_2 = uilabel(app.GridLayout3);
            app.BackPumpmWLabel_2.Text = 'Back Pump/mW';
            app.BackPumpmWSlider = uislider(app.GridLayout3);
            app.BackPumpmWSlider.Limits = [0 500];
            app.BackPumpmWSlider.ValueChangingFcn = createCallbackFcn(app, @BackPumpmWSliderValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout2);
            title(app.UIAxes_2, 'Signal')
            xlabel(app.UIAxes_2, 'distance/km')
            ylabel(app.UIAxes_2, 'Power/mW')
            app.UIAxes_4 = uiaxes(app.GridLayout2);
            title(app.UIAxes_4, 'Pump')
            xlabel(app.UIAxes_4, 'distance/km')
            ylabel(app.UIAxes_4, 'Power/mW')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bidirectionalPumping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end